classdef FitnessTrackerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SummaryPanel       matlab.ui.container.Panel
        EfficiencyLabel    matlab.ui.control.Label
        IntensityLabel     matlab.ui.control.Label
        CaloriesLabel      matlab.ui.control.Label
        ZoneBarAxes        matlab.ui.control.UIAxes
        FeedbackPanel      matlab.ui.container.Panel
        FeedbackLamp       matlab.ui.control.Lamp
        FeedbackTextArea   matlab.ui.control.TextArea
        ControlsPanel      matlab.ui.container.Panel
        ZoneDropDown       matlab.ui.control.DropDown
        ZoneDropDownLabel  matlab.ui.control.Label
        SessionDropDown    matlab.ui.control.DropDown
        SessionLabel       matlab.ui.control.Label
        LoadDataButton     matlab.ui.control.Button
        GraphPanel         matlab.ui.container.Panel
        HRAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Sessions % Description
        People % Description
        MetRef % Description
        CurrentSession % Description
        CurrentPerson % Description
    end
    
    methods (Access = private)
        
        function updateDashboard(app)
    if isempty(app.CurrentSession)
        return  % nothing loaded yet, nothing to update
    end

    session = app.CurrentSession;

    % Find the matching person
    personIds = cellfun(@(p) p.person_id, app.People, 'UniformOutput', false);
    personIdx = find(strcmp(personIds, session.person_id));
    person = app.People{personIdx};
    app.CurrentPerson = person;

    % --- Clean the signals ---
    hr = fillmissing(session.heart_rate, 'linear');
    accelMag = sqrt(session.accel_x.^2 + session.accel_y.^2 + session.accel_z.^2);
    accelMag = fillmissing(accelMag, 'linear');
    accelMag = movmean(accelMag, 10);

    % --- Heart rate zones ---
    pctMaxHR = hr / person.estimated_max_hr_bpm;
    zoneEdges = [0, 0.5, 0.6, 0.7, 0.8, 0.9, Inf];
    zoneBin = discretize(pctMaxHR, zoneEdges);
    counts = histcounts(zoneBin, 1:7);
    zonePercent = counts / sum(counts) * 100;

    % --- Movement classification ---
    movementVariability = std(accelMag);
    if movementVariability < 0.20
        movementLevel = 'Low Motion';
    elseif movementVariability < 0.30
        movementLevel = 'Moderate Motion';
    else
        movementLevel = 'High Motion';
    end

    % --- MET lookup + calories ---
    metActivityNames = cellfun(@(m) m.activity_type, app.MetRef, 'UniformOutput', false);
    metValues = cellfun(@(m) m.typical_MET, app.MetRef);
    matchIdx = strcmp(metActivityNames, session.activity_type);
    MET = metValues(matchIdx);

    duration_hours = (session.time(end) - session.time(1)) / 3600;
    calories = MET * person.weight_kg * duration_hours;

    % --- Intensity score ---
    maxPossibleMET = max(metValues);
    metNormalized = MET / maxPossibleMET;
    accelNormalized = min(1, movementVariability / 0.5);
    intensityScore = 100 * (0.5 * metNormalized + 0.5 * accelNormalized);

    % --- Efficiency ---
    durationMinutes = duration_hours * 60;
    efficiencyScore = calories / durationMinutes;

    % ===== Now update the actual UI components =====

    % Graph: heart rate over time
    timeMinutes = (session.time - session.time(1)) / 60;
    plot(app.HRAxes, timeMinutes, hr, 'LineWidth', 1.5);
    xlabel(app.HRAxes, 'Time (minutes)');
    ylabel(app.HRAxes, 'Heart Rate (bpm)');
    title(app.HRAxes, session.session_id);
    % Highlight selected HR zone on the graph, if one is chosen
    selectedZone = app.ZoneDropDown.Value;
    zoneBoundsMap = containers.Map(...
        {'Zone 1','Zone 2','Zone 3','Zone 4','Zone 5'}, ...
        {[0.5 0.6], [0.6 0.7], [0.7 0.8], [0.8 0.9], [0.9 1.5]});

    if ~strcmp(selectedZone, 'None')
        bounds = zoneBoundsMap(selectedZone) * person.estimated_max_hr_bpm;
        hold(app.HRAxes, 'on');
        yline(app.HRAxes, bounds(1), '--', 'Color', [0.9 0.5 0.1], 'LineWidth', 1.5);
        yline(app.HRAxes, bounds(2), '--', 'Color', [0.85 0.1 0.1], 'LineWidth', 1.5);
        hold(app.HRAxes, 'off');
    end

    % Lamp: color-coded visual alert based on intensity
    if intensityScore >= 70
        app.FeedbackLamp.Color = [0.2 0.8 0.2]; % green
    elseif intensityScore >= 40
        app.FeedbackLamp.Color = [0.95 0.7 0.1]; % yellow
    else
        app.FeedbackLamp.Color = [0.85 0.2 0.2]; % red
    end

    % Summary text
    app.FeedbackTextArea.Value = {
        sprintf('Activity: %s', session.activity_type)
        sprintf('Movement level: %s', movementLevel)
        sprintf('Calories burned: %.1f kcal', calories)
        sprintf('Intensity score: %.1f / 100', intensityScore)
        sprintf('Efficiency: %.2f cal/min', efficiencyScore)
        sprintf('Zone 2 time: %.1f%%', zonePercent(3))
    };
    % Zone breakdown bar chart
    bar(app.ZoneBarAxes, zonePercent);
    app.ZoneBarAxes.XTickLabel = {'Below Z1','Z1','Z2','Z3','Z4','Z5'};
    xlabel(app.ZoneBarAxes, 'Heart rate zone');
    ylabel(app.ZoneBarAxes, '% of session');
    title(app.ZoneBarAxes, 'Time in each zone');

    % Personalized feedback message
    if intensityScore >= 70
        feedbackMsg = sprintf(['Great session! You spent most of your time in a strong ' ...
            'effort zone. Keep this pace for your %s goal.'], strrep(person.fitness_goal, '_', ' '));
    elseif intensityScore >= 40
        feedbackMsg = sprintf(['Moderate effort this session. To better match your %s goal, ' ...
            'try picking up the pace or duration slightly next time.'], strrep(person.fitness_goal, '_', ' '));
    else
        feedbackMsg = sprintf(['This session stayed pretty light. For your %s goal, aim to spend ' ...
            'more time pushing your heart rate higher next workout.'], strrep(person.fitness_goal, '_', ' '));
    end
    app.FeedbackTextArea.Value = {feedbackMsg};
    app.CaloriesLabel.Text = sprintf('Calories: %.0f kcal', calories);
    app.IntensityLabel.Text = sprintf('Intensity: %.0f / 100', intensityScore);
    app.EfficiencyLabel.Text = sprintf('Efficiency: %.1f cal/min', efficiencyScore);
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select wearable_sensor_data.mat');
            if isequal(file, 0)
                return  % user clicked Cancel
            end

            loadedData = load(fullfile(path, file));
            app.Sessions = loadedData.sessions;
            app.People = loadedData.people;
            app.MetRef = loadedData.activity_met_reference;

            % Pull every session's ID into the dropdown
            sessionIds = cellfun(@(s) s.session_id, app.Sessions, 'UniformOutput', false);
            app.SessionDropDown.Items = sessionIds;
            app.SessionDropDown.Value = sessionIds{1};

            app.CurrentSession = app.Sessions{1};
            app.updateDashboard();
        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            value = app.SessionDropDown.Value;
            selectedId = app.SessionDropDown.Value;
            sessionIds = cellfun(@(s) s.session_id, app.Sessions, 'UniformOutput', false);
            idx = find(strcmp(sessionIds, selectedId));
            app.CurrentSession = app.Sessions{idx};
            app.updateDashboard();
        end

        % Value changed function: ZoneDropDown
        function ZoneDropDownValueChanged(app, event)
            value = app.ZoneDropDown.Value;
            app.updateDashboard();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Heart Rate';
            app.HRAxes = uiaxes(app.GraphPanel);
            title(app.HRAxes, 'Title')
            xlabel(app.HRAxes, 'X')
            ylabel(app.HRAxes, 'Y')
            zlabel(app.HRAxes, 'Z')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.LoadDataButton = uibutton(app.ControlsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SessionLabel = uilabel(app.ControlsPanel);
            app.SessionLabel.Text = 'Session:';
            app.SessionDropDown = uidropdown(app.ControlsPanel);
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.ZoneDropDownLabel = uilabel(app.ControlsPanel);
            app.ZoneDropDownLabel.Text = 'Highlight Zone:';
            app.ZoneDropDown = uidropdown(app.ControlsPanel);
            app.ZoneDropDown.Items = {'None', 'Zone 1', 'Zone 2', 'Zone 3', 'Zone 4', 'Zone 5'};
            app.ZoneDropDown.ValueChangedFcn = createCallbackFcn(app, @ZoneDropDownValueChanged, true);
            app.ZoneDropDown.Value = 'None';
            app.FeedbackPanel = uipanel(app.UIFigure);
            app.FeedbackPanel.Title = 'How You''re Doing';
            app.FeedbackTextArea = uitextarea(app.FeedbackPanel);
            app.FeedbackLamp = uilamp(app.FeedbackPanel);
            app.SummaryPanel = uipanel(app.UIFigure);
            app.SummaryPanel.Title = 'Summary';
            app.ZoneBarAxes = uiaxes(app.SummaryPanel);
            title(app.ZoneBarAxes, 'Title')
            xlabel(app.ZoneBarAxes, 'X')
            ylabel(app.ZoneBarAxes, 'Y')
            zlabel(app.ZoneBarAxes, 'Z')
            app.CaloriesLabel = uilabel(app.SummaryPanel);
            app.CaloriesLabel.Text = 'Calories';
            app.IntensityLabel = uilabel(app.SummaryPanel);
            app.IntensityLabel.Text = 'Intensity';
            app.EfficiencyLabel = uilabel(app.SummaryPanel);
            app.EfficiencyLabel.Text = 'Efficiency';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FitnessTrackerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end